classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        DevelopDeployTab            matlab.ui.container.Tab
        RefreshButton               matlab.ui.control.Button
        SaveButton                  matlab.ui.control.Button
        GeneratePLCButton           matlab.ui.control.Button
        AddHOSTButton               matlab.ui.control.Button
        AddITFCButton               matlab.ui.control.Button
        HOSTPanel                   matlab.ui.container.Panel
        InterfacePanel              matlab.ui.container.Panel
        NewSwitch                   matlab.ui.control.Switch
        NewSwitchLabel              matlab.ui.control.Label
        FilenameEditField           matlab.ui.control.EditField
        FilenameEditField_2Label    matlab.ui.control.Label
        SubmodelsPanel              matlab.ui.container.Panel
        AddSUBMODELButton           matlab.ui.control.Button
        LoadButton                  matlab.ui.control.Button
        TestTab                     matlab.ui.container.Tab
        SaveButton_2                matlab.ui.control.Button
        Button_2                    matlab.ui.control.Button
        Button                      matlab.ui.control.Button
        DelaysamplesEditField       matlab.ui.control.NumericEditField
        DelaysamplesEditFieldLabel  matlab.ui.control.Label
        FinddelayButton             matlab.ui.control.Button
        CheckdatatransmissionPanel  matlab.ui.container.Panel
        RunSimulinkmodelsButton     matlab.ui.control.Button
        SelectHostDropDown          matlab.ui.control.DropDown
        SelectHostDropDownLabel     matlab.ui.control.Label
        EntriesDropDown             matlab.ui.control.DropDown
        EntriesDropDownLabel        matlab.ui.control.Label
        LoadHostDataButton          matlab.ui.control.Button
        GenerateMATfilefromHOSToutputsButton  matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
        ContextMenu_Cref            matlab.ui.container.ContextMenu
        AddMatlabFieldsMenu         matlab.ui.container.Menu
        ContextMenuPlot             matlab.ui.container.ContextMenu
        GridonMenu                  matlab.ui.container.Menu
        BrushdatasyncITFCappMenu    matlab.ui.container.Menu
    end

    
    properties (Access = public)
        Data 
    end
    
    methods (Access = private)

        function updateHOSTTable(app, wfcfw)

            [head_labels, g, itemfn] = initialize_table(app, 'host', wfcfw);

            DataTable = cell(length(itemfn), length(head_labels));

            for iitem = 1 : length(itemfn)

                item = wfcfw.host.(itemfn{iitem});
                item.CheckFlags(wfcfw);

                % definition of the table entries
                tag = item.appTag;
                Flag_Generate_PLC_app = logical(item.Flag_Generate_PLC_app);
                Flag_C_ref_found = item.Flags.C_ref_found;
                %                 Flag_can_create_SL_model =  item.Flags.can_create_sl_model;
                %                 Flag_SLmodel_Ready = sm.Flags.SLmodel_Ready;
                Flag_ready = item.Flags.PLC_codeGen_Ready;

                if ~Flag_ready
                    Flag_Generate_PLC_app = false;
                    app.Data.wfcfw.host.(tag).Flag_Generate_PLC_app = Flag_Generate_PLC_app; % if compilation should not happen, i must tell my framework
                    EnableCheckBox_PLC_app = false;
                else
                    EnableCheckBox_PLC_app = true;
                end

                %% Create Field with apptag
                iCol = find(contains(head_labels,'appTag'));
                edt = insert_entry_table(app, 'tag', iCol, g, iitem, tag);
                DataTable{iitem,iCol} = edt;

                %% Generate PLC
                iCol = find(contains(head_labels,'Generate PLC'));
                cb = insert_entry_table(app, 'Generate PLC', iCol, g, iitem, Flag_Generate_PLC_app, EnableCheckBox_PLC_app, class(item), tag);
                DataTable{iitem,iCol} = cb;                

                %% Flag_C_ref_found
                iCol = find(contains(head_labels,'C ref found'));
                lmp = insert_entry_table(app, 'C ref found', iCol, g, iitem, Flag_C_ref_found, class(item), tag);
                DataTable{iitem,iCol} = lmp;

                %% Flag_ready
                iCol = find(contains(head_labels,'PLCgen-Ready'));
                lmp = insert_entry_table(app, 'PLCgen-Ready', iCol, g, iitem, Flag_ready, class(item), tag);
                DataTable{iitem,iCol} = lmp;

                %% Details
                iCol = find(contains(head_labels,'Details'));
                button = insert_entry_table(app, 'Details', iCol, g, iitem, class(item), tag);
                DataTable{iitem,iCol} = button;

            end
            app.Data.host_Table = DataTable;


        end


        function updateITFCTable(app, wfcfw)

            [head_labels, g, itemfn] = initialize_table(app, 'ITFC', wfcfw);

            DataTable = cell(length(itemfn), length(head_labels));

            for iitem = 1 : length(itemfn)

                item = wfcfw.itfc.(itemfn{iitem});
                item.CheckFlags(wfcfw);

                % definition of the table entries
                tag = item.appTag;
                Flag_Generate_PLC_app = logical(item.Flag_Generate_PLC_app);
                Flag_C_ref_found = item.Flags.C_ref_found;
                %                 Flag_can_create_SL_model =  item.Flags.can_create_sl_model;
                %                 Flag_SLmodel_Ready = sm.Flags.SLmodel_Ready;
                Flag_ready = item.Flags.PLC_codeGen_Ready;

                if ~Flag_ready
                    Flag_Generate_PLC_app = false;
                    app.Data.wfcfw.itfc.(tag).Flag_Generate_PLC_app = Flag_Generate_PLC_app; % if compilation should not happen, i must tell my framework
                    EnableCheckBox_PLC_app = false;
                else
                    EnableCheckBox_PLC_app = true;
                end

                %% Create Field with apptag
                iCol = find(contains(head_labels,'appTag'));
                edt = insert_entry_table(app, 'tag', iCol, g, iitem, tag);
                DataTable{iitem,iCol} = edt;

                %% Generate PLC
                iCol = find(contains(head_labels,'Generate PLC'));
                cb = insert_entry_table(app, 'Generate PLC', iCol, g, iitem, Flag_Generate_PLC_app, EnableCheckBox_PLC_app, class(item), tag);
                DataTable{iitem,iCol} = cb;                

                %% Flag_C_ref_found
                iCol = find(contains(head_labels,'C ref found'));
                lmp = insert_entry_table(app, 'C ref found', iCol, g, iitem, Flag_C_ref_found, class(item), tag);
                DataTable{iitem,iCol} = lmp;

                %% Flag_ready
                iCol = find(contains(head_labels,'PLCgen-Ready'));
                lmp = insert_entry_table(app, 'PLCgen-Ready', iCol, g, iitem, Flag_ready, class(item), tag);
                DataTable{iitem,iCol} = lmp;

                %% Create Empty ITFC
                iCol = find(contains(head_labels, 'Create Empty ITFC'));
                button = insert_entry_table(app, 'Create Empty ITFC', iCol, g, iitem, tag);
                DataTable{iitem,iCol} = button;

                %% Create Random ITFC
                iCol = find(contains(head_labels, 'Create Random ITFC'));
                button = insert_entry_table(app, 'Create Random ITFC', iCol, g, iitem, tag);
                DataTable{iitem,iCol} = button;

                %% Load ITFC
                iCol = find(contains(head_labels, 'Load ITFC'));
                button = insert_entry_table(app, 'Load ITFC', iCol, g, iitem, tag);
                DataTable{iitem,iCol} = button;

                %% Details
                iCol = find(contains(head_labels,'Details'));
                button = insert_entry_table(app, 'Details', iCol, g, iitem, class(item), tag);
                DataTable{iitem,iCol} = button;

%                 item = uibutton(g,'ButtonPushedFcn',@(obj, event) button_details_Callback(app,modeltag));
%                 item.Layout.Row = iSubmodel+1;
%                 item.Layout.Column = iCol;
%                 item.Text = 'Open';
%                 item.Enable = true;
%                 resizeTableColumn(app, item, g, item.Layout.Column)  
%                 app.Data.Table{iSubmodel,item.Layout.Column} = item;

                
            end

            app.Data.itfc_Table = DataTable;
        end

        
        function updateSubmodelTable(app, wfcfw)
             

            [head_labels, g, smfn] = initialize_table(app, 'submodels', wfcfw);
            
            DataTable = cell(length(smfn), length(head_labels));             
   
            for iSubmodel = 1 : length(smfn)
                
                sm = wfcfw.sub_models.(smfn{iSubmodel});
                sm.CheckFlags(wfcfw);
                
                % definition of the table entries
                modelTag = sm.modelTag;
                Flag_Generate_PLC_app = logical(sm.Flag_Generate_PLC_app);
                Flag_C_ref_found = sm.Flags.C_ref_found;
%                 Flag_C_ref_codegen_found = sm.Flags.C_ref_codegen_found; % this finds the reference C file with the matlab fields necessary for code generation                  
                Flag_can_create_SL_model =  sm.Flags.can_create_sl_model; 
%                 Flag_isDefined = sm.Flags.isDefined;
                Flag_SLmodel_Ready = sm.Flags.SLmodel_Ready;
                Flag_ready = sm.Flags.PLC_codeGen_Ready;
                
                if ~Flag_ready
                    Flag_Generate_PLC_app = false;
                    app.Data.wfcfw.sub_models.(modelTag).Flag_Generate_PLC_app = Flag_Generate_PLC_app; % if compilation should not happen, i must tell my framework
                    EnableCheckBox_PLC_app = false;
                else
                    EnableCheckBox_PLC_app = true;                    
                end
                
                
                %% Create Field with modeltag
                iCol = find(contains(head_labels,'modelTag'));
                edt = insert_entry_table(app, 'tag', iCol, g, iSubmodel, modelTag);
                DataTable{iSubmodel,iCol} = edt;
                
                %% Generate PLC
                iCol = find(contains(head_labels,'Generate PLC'));
                cb = insert_entry_table(app, 'Generate PLC', iCol, g, iSubmodel, Flag_Generate_PLC_app, EnableCheckBox_PLC_app, class(sm), modelTag);
                DataTable{iSubmodel,iCol} = cb;                

                %% Flag_C_ref_found
                iCol = find(contains(head_labels,'C ref found'));
                lmp = insert_entry_table(app, 'C ref found', iCol, g, iSubmodel, Flag_C_ref_found, class(sm), modelTag);
                DataTable{iSubmodel,iCol} = lmp;

                %% Flag_ready
                iCol = find(contains(head_labels,'PLCgen-Ready'));
                lmp = insert_entry_table(app, 'PLCgen-Ready', iCol, g, iSubmodel, Flag_ready, class(sm), modelTag);
                DataTable{iSubmodel,iCol} = lmp;

                    
                %%%%%%%%%%%%%%% Flag_can_create_SL_model
                iCol = find(contains(head_labels,'Create Simulink Model'));
                item = uibutton(g,'ButtonPushedFcn',@(obj, event) button_emptySL_Callback(app,modelTag));
                item.Layout.Row = iSubmodel+1;
                item.Layout.Column = iCol;
                item.Text = 'Create';
                item.Enable = Flag_can_create_SL_model;
                
                resizeTableColumn(app, item, g, item.Layout.Column)  
                app.Data.Table{iSubmodel,item.Layout.Column} = item;
                
                %% Details
                iCol = find(contains(head_labels,'Details'));
                button = insert_entry_table(app, 'Details', iCol, g, iSubmodel, class(sm), modelTag);
                DataTable{iSubmodel,iCol} = button;

                
                %%%%%% SLmodel-Ready
                iCol = find(contains(head_labels,'SLmodel-Ready'));
                lmp = uilamp(g);
                lmp.Layout.Row = iSubmodel+1;
                lmp.Layout.Column = iCol;
                if Flag_SLmodel_Ready
                lmp.Color = [0 1 0];
                else
                lmp.Color = [1 0 0];                    
                end
                resizeTableColumn(app, lmp, g, lmp.Layout.Column)                
                app.Data.Table{iSubmodel,lmp.Layout.Column} = lmp;                 


            end

            app.Data.Table = DataTable; 

        end
        
        function button_emptySL_Callback(app,modeltag)
            
            wfcfw = app.Data.wfcfw;
            
            newmodel = wfcfw.sub_models.(modeltag);
            newmodel.create_empty_slmodel(wfcfw);

            updateSubmodelTable(app, wfcfw)

        end

        
%         function resizeItemPosition(app, item, pos_idx)
%             
%             i_x = pos_idx(1);
%             i_y = pos_idx(2);
%             pw =  app.Data.Settings.UIPanelColumns_width;
%             ph = app.Data.Settings.UIPanelRows_height;
%             y0 = app.Data.Settings.UIPanelRows_y0;
%                        
%             x = (i_x-1)*pw;
%             y = y0 - (i_y-1)*ph;
%             
%            item.Position = [x y  pw ph];
%                         
%         end
        
%         function resizeTableColumn(app, item, parent, iCol)
%             % this function was created because the fit property of
%             % UIgridlayout didn't work properly, and could not resize cells
%             % according to the string size
%             
%                 if strcmp(class(item),'matlab.ui.control.EditField')
%                 kk = 1.2;
%                 cc = uicontrol('style','text');
%                 cc.FontUnits = "pixels";
%                 cc.FontName = item.FontName;
%                 cc.FontWeight = item.FontWeight;
%                 cc.FontSize = item.FontSize;
%                 cc.FontAngle = item.FontAngle;
%                 cc.String = item.Value;
%                 Ext = get(cc, 'Extent');
%                 
%                 if Ext(3)*kk > parent.ColumnWidth{iCol}
%                     parent.ColumnWidth{iCol} = Ext(3)*kk;                                    
%                 else
%                 end
%                 
%                 elseif strcmp(class(item),'matlab.ui.control.CheckBox')
%                     
%                 else
%                     
%                 end
%                 
%             
%         end
        
%         function createHeader(app, parent)
%             
%             header_labels = app.Data.Settings.header_labels;
%             for ih = 1 : length(header_labels)
%                 
%                 
%                 edt = uieditfield(parent);
%                 edt.Layout.Row = 1;
%                 edt.Layout.Column = ih;
%                 edt.Editable = false;
%                 edt.FontWeight = 'bold';
%                 edt.BackgroundColor = [0.9 0.9 0.9];
%                 edt.Value = header_labels{ih};
%                 resizeTableColumn(app, edt, parent, ih)
%  
%             end
%      
%         end


        function plot_var(app)

           value = app.EntriesDropDown.Value;
            
           wfcfw = app.Data.wfcfw;

           ha = app.SelectHostDropDown.Value;
           
           ielem = cell2mat(cellfun(@(x) strcmp(x, value), {wfcfw.host.(ha).Variables.TagName}, 'Uniformoutput', false));

           var =  wfcfw.host.(ha).Variables(ielem);

           if strcmpi(var.VarType,'char')
                warning('Cannot plot string variable!')
                return
           end

           cla( app.UIAxes , 'reset') 

           if ~isfield(var.test_outputs,'hostvar_timestamp')
                error('Could not plot host variable "%s". Could not find time history in HOST App.',var.TagName)
           end

           xh = var.test_outputs.hostvar_timestamp;
           yh = var.test_outputs.hostvar_th;

           plot(app.UIAxes, xh, yh,'displayname',[ha])

           if strcmpi(var.Action,'read')
               yi = var.test_outputs.itfc_var_th;
%                nElem = size(yi,1);

               adddelay = app.DelaysamplesEditField.Value;

               xi = var.test_outputs.itfc_var_timestamp - seconds(adddelay*wfcfw.settings.sample_time);
%                xi = seconds(([0 : nElem-1] - adddelay)*wfcfw.settings.sample_time) + xh(1);

%                var.test_outputs.iftc_var_timestamp = xi;
               hold(app.UIAxes, 'on' )
               plot(app.UIAxes, xi, yi,'displayname',[var.parent_App])
           end

           if isfield(var.test_outputs,'sl_th')
               yi = var.test_outputs.sl_th;
               xi = var.test_outputs.sl_time_stamp; %- seconds(adddelay*wfcfw.settings.sample_time);

               sm_var = var.test_outputs.sl_model_tagname;
               hold(app.UIAxes, 'on' )
               plot(app.UIAxes, xi, yi,'displayname',[sm_var])

           end

           legend(app.UIAxes, 'Interpreter', 'none')
           xlabel(app.UIAxes, 'TimeStamp')
           ylabel(app.UIAxes, var.Units)


        end
    end
    
    methods (Access = public)

        function button_load_itfc_Callback(app,varargin)
            
            wfcfw = app.Data.wfcfw;
            tag = varargin{1}{1};

            model = wfcfw.itfc.(tag);
            model.load_itfc_file();

        end

        function button_empty_itfc_Callback(app,varargin)
            
            wfcfw = app.Data.wfcfw;
            tag = varargin{1}{1};

            model = wfcfw.itfc.(tag);
            model.create_empty_itfc();

        end

        function button_random_itfc_Callback(app,varargin)
            
            wfcfw = app.Data.wfcfw;
            tag = varargin{1}{1};

            model = wfcfw.itfc.(tag);
            model.create_random_itfc(wfcfw.settings.sample_time);

        end

        function button_details_Callback(app,varargin)
            
            itemclass = varargin{1}{2};
            tag = varargin{1}{1};
            wfcfw = app.Data.wfcfw;

            switch itemclass
                case 'submodel'
                    modelTag = tag;
                    sm = wfcfw.sub_models.(modelTag);
                    smvn = app.Data.submodels_labels;
                    prompt = cellfun(@(x) ['Enter ' x ':'], smvn, 'UniformOutput',false);
                    dlgtitle = 'Submodel Settings';
            
                    dims = [1];
                    definput = cellfun(@(x) num2str(sm.(x)), smvn, 'UniformOutput',false);
                    answer = inputdlg(prompt,dlgtitle,dims,definput);
            
                    if isempty(answer)                
                        return
                    end
            
                    tab = array2table(answer', 'VariableNames',smvn);
            
                    sm.create_new(tab(1,:));
                    wfcfw.sub_models.(sm.modelTag) = sm;

                case 'itfc_app'

                    sm = wfcfw.itfc.(tag);
                    smvn = app.Data.itfc_labels;
                    prompt = cellfun(@(x) ['Enter ' x ':'], smvn, 'UniformOutput',false);
                    dlgtitle = 'itfc Settings';
            
                    dims = [1];
                    definput = cellfun(@(x) num2str(sm.(x)), smvn, 'UniformOutput',false);
                    answer = inputdlg(prompt,dlgtitle,dims,definput);
            
                    if isempty(answer)                
                        return
                    end
            
                    tab = array2table(answer', 'VariableNames',smvn);
                    sm = itfc_app(tab(1,:));
                    sm.load_svi_variables(wfcfw);

                    sm.checkVariables(wfcfw.settings.plcdict);

                    wfcfw.itfc.(sm.appTag) = sm;

                    case 'host_app'

                    sm = wfcfw.host.(tag);
                    smvn = app.Data.host_labels;
                    prompt = cellfun(@(x) ['Enter ' x ':'], smvn, 'UniformOutput',false);
                    dlgtitle = 'host Settings';
            
                    dims = [1];
                    definput = cellfun(@(x) num2str(sm.(x)), smvn, 'UniformOutput',false);
                    answer = inputdlg(prompt,dlgtitle,dims,definput);
            
                    if isempty(answer)                
                        return
                    elseif isequal(answer', definput)
                        return
                    end
           
                    tab = array2table(answer', 'VariableNames',smvn);
                    sm = host_app(tab(1,:));
                    sm.load_svi_variables(wfcfw);

%                     sm.checkVariables(wfcfw.settings.plcdict);

                    wfcfw.host.(sm.appTag) = sm;

                otherwise
                    error('Detail button not yet implemented for %s', itemclass)

            end
            
            app.Data.wfcfw = wfcfw;            
            updateSubmodelTable(app, wfcfw)
            updateITFCTable(app, wfcfw)
            updateHOSTTable(app, wfcfw)
        end

        function add_matlab_fields_callback(app, varargin)

            wfcfw = app.Data.wfcfw;
            tag = varargin{3}{1};
            itemclass = varargin{3}{2};

            switch itemclass

                case 'submodel'
                    sm = wfcfw.sub_models.(tag);
                    sm.add_matlabcodegen_fields(wfcfw)
                    updateSubmodelTable(app, wfcfw);
                case 'itfc_app'
                    sm = wfcfw.itfc.(tag);
                    sm.add_matlabcodegen_fields(wfcfw)
                    updateITFCTable(app, wfcfw);
                case 'host_app'
                    sm = wfcfw.host.(tag);
                    sm.add_matlabcodegen_fields(wfcfw)
                    updateHOSTTable(app, wfcfw);

            end
        end

        function cBoxChanged_GenPLC(app, varargin)

            ev = varargin{2};
            Flag = logical(ev.Value);

            switch varargin{4}
                case 'submodel'
                    app.Data.wfcfw.sub_models.(varargin{3}).Flag_Generate_PLC_app = Flag;
                case 'itfc_app'
                    app.Data.wfcfw.itfc.(varargin{3}).Flag_Generate_PLC_app = Flag;

                case 'host_app'
                    app.Data.wfcfw.host.(varargin{3}).Flag_Generate_PLC_app = Flag;
            end

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

                %% sizing and definition of SubModel table
                nCol = 5;
                nRow = 6;
                app.Data.Settings.UIPanelColumns_width = app.SubmodelsPanel.Position(3)/nCol;
                app.Data.Settings.UIPanelRows_height = app.SubmodelsPanel.Position(4)/nRow;
                app.Data.Settings.UIPanelRows_y0 = app.SubmodelsPanel.Position(2) + app.SubmodelsPanel.Position(4);
                app.Data.Settings.header_labels = {'modelTag', 'Generate PLC', 'C ref found', 'SLmodel-Ready' , 'PLCgen-Ready', 'Create Simulink Model','Details'};
                app.Data.submodels_labels = {'modelTag' 'PLCApp_folder' 'slmodel_folder' 'slmodel_name' 'slccode_folder' ...
                	'refC_name' 'Flag_Generate_PLC_app' 'host_apptag' 'Comment'};

                %% sizing and definition of ITFC table
                nCol = 5;
                nRow = 6;
                app.Data.Settings.itfc_UIPanelColumns_width = app.InterfacePanel.Position(3)/nCol;
                app.Data.Settings.itfc_UIPanelRows_height = app.InterfacePanel.Position(4)/nRow;
                app.Data.Settings.itfc_UIPanelRows_y0 = app.InterfacePanel.Position(2) + app.InterfacePanel.Position(4);
                app.Data.Settings.itfc_header_labels = {'appTag', 'Generate PLC', 'C ref found', 'PLCgen-Ready','Create Empty ITFC','Create Random ITFC', 'Load ITFC','Details'};
                app.Data.itfc_labels = {'appTag' 'PLCApp_folder' 'refC_name' 'Flag_Generate_PLC_app' 'Flag_Create_test_ITFC' 'test_ITFC_filename' 'Comment'};

                %% sizing and definition of HOST table
                nCol = 5;
                nRow = 6;
                app.Data.Settings.host_UIPanelColumns_width = app.HOSTPanel.Position(3)/nCol;
                app.Data.Settings.host_UIPanelRows_height = app.HOSTPanel.Position(4)/nRow;
                app.Data.Settings.host_UIPanelRows_y0 = app.HOSTPanel.Position(2) + app.HOSTPanel.Position(4);
                app.Data.Settings.host_header_labels = {'appTag', 'Generate PLC', 'C ref found', 'PLCgen-Ready','Details'};
                app.Data.host_labels = {'appTag' 'PLCApp_folder' 'refC_name' 'Flag_Generate_PLC_app' ...
                    'FAST_FREQ_RATIO'	'SLOW_FREQ_RATIO'	'CTRL_FREQ_RATIO'	'out_filename'	'output_path_in_PLC' 'output_file_duration_s' 'Comment'};

                %% add folder
                addpath(genpath('.\routines'))

                %% Adding empty test framework
                app.Data.t_fw = test_framework(); 

        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            
            warning('off','all')
            %% add folder
            rmpath(genpath('.'));
            addpath(genpath('.\routines'))
            warning('on','all')
           
            [file,path] = uigetfile('*');
            
            framework_inputs = fullfile(path, file);
            
            if exist(framework_inputs) == 0                
                warning('Input File not found.');
                return
            end
            
            app.FilenameEditField.Value = framework_inputs;
              
            [~ ,~ ,ext] = fileparts(framework_inputs);

            if strcmpi(ext,'.mat')

                load(framework_inputs);
                wfcfw.add_folders(); % add matlab folders to current paths


            elseif strcmpi(ext,'.xlsx')

                wfcfw = wfc_framework;  % initialize empty object
                wfcfw.create_new(framework_inputs);
                wfcfw.add_folders(); % add matlab folders to current paths

            end

            updateSubmodelTable(app, wfcfw);
            updateITFCTable(app, wfcfw);
            updateHOSTTable(app, wfcfw);
            app.Data.wfcfw = wfcfw;
            
            
        end

        % Value changed function: NewSwitch
        function NewSwitchValueChanged(app, event)
            value = app.NewSwitch.Value;
            rmpath(genpath('.'));
            addpath(genpath('routines'))
            
            if strcmp(value,'On')
                app.LoadButton.Enable = "off";
                app.FilenameEditField.Enable = "off";
%                app.UITable.Data = {}; % delete all data in the existing table
                
                root_folder = uigetdir('.','Where do you want to create your new project?');

                if root_folder == 0
                    return
                end
              
                prompt = {'New project name:'};
                dlgtitle = 'Define new project';
                definput = {'new_project'};
                dims = [1 50];               
                framework_inputs_folder = inputdlg(prompt, dlgtitle, dims,  definput);                            

                if isempty(framework_inputs_folder)
                    return
                end

                input_fn = 'inputfile.xlsx';
                create_empty_project_input(root_folder, framework_inputs_folder{1}, input_fn);

                fn = fullfile(root_folder, framework_inputs_folder{1}, input_fn);
                wfcfw = wfc_framework;  % initialize empty object
                wfcfw.create_new(fn);
                wfcfw.add_folders(); % add matlab folders to current paths

                updateSubmodelTable(app, wfcfw);   
                updateHOSTTable(app, wfcfw);                
                updateITFCTable(app, wfcfw);                

                app.Data.wfcfw = wfcfw; 
                
                app.NewSwitch.Value = 'Off';
                app.LoadButton.Enable = "on";
                app.FilenameEditField.Enable = "on";
                

            elseif strcmp(value,'Off')
                app.LoadButton.Enable = "on";
                app.FilenameEditField.Enable = "on";
                
            end
            
            
        end

        % Button pushed function: AddSUBMODELButton
        function AddSUBMODELButtonPushed(app, event)
                       
                wfcfw = app.Data.wfcfw; 
                smvn = app.Data.submodels_labels;
                newRow = cell(1, length(smvn));
                tab = array2table(newRow, 'VariableNames',smvn);
                
                prompt = {'Model Tag:'};
                dlgtitle = 'Create new submodel:';
                definput = {'New_Submodel'};
                dims = [1 50];                
                input = inputdlg(prompt, dlgtitle, dims,  definput);  

                if isempty(input)
                    return
                elseif ~isempty(wfcfw.sub_models) && sum(strcmp(input{1}, fieldnames(wfcfw.sub_models)))>0 % there is already a submodel with this name
                    answer = questdlg(['The project already contains a submodel named "' input{1} ...
                        '". Do you want to overwrite it?'], 'Submodel with same name detected', ...
                        'Yes', 'No','No');
                    switch answer
                        case 'Yes'
                            
                        case 'No'
                            return
                    end
                end
                
                sm = submodel;

                % default settings are used
                tab.Flag_Generate_PLC_app = false;
                tab.modelTag = input{1};
                tab.PLCApp_folder = input{1};
                tab.slmodel_folder = input{1};
                tab.slmodel_name = input{1};
                tab.slccode_folder = input{1};
                tab.refC_name = input{1};
                
                
                sm.create_new(tab(1,:));
                wfcfw.sub_models.(sm.modelTag) = sm;

                app.Data.wfcfw = wfcfw;                                     
                updateSubmodelTable(app, wfcfw)

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)

            wfcfw = app.Data.wfcfw;            
            wfcfw.save_to_file(app.Data.submodels_labels, app.Data.itfc_labels, app.Data.host_labels);
            
        end

        % Button pushed function: GeneratePLCButton
        function GeneratePLCButtonPushed(app, event)
            
            wfcfw = app.Data.wfcfw;            
            wfcfw.generate_plccode_sl();
            wfcfw.generate_plccode_itfc();
            wfcfw.generate_plccode_host();

        end

        % Callback function
        function LoadSubmodelsButtonPushed(app, event)
            %% CRS 20231113 Removed functionality

            wfcfw = app.Data.wfcfw;            
            wfcfw.load_submodels();
        end

        % Button pushed function: AddITFCButton
        function AddITFCButtonPushed(app, event)

                wfcfw = app.Data.wfcfw; 
                itfcvn = app.Data.itfc_labels;
                newRow = cell(1, length(itfcvn));
                tab = array2table(newRow, 'VariableNames',itfcvn);
                
                prompt = {'ITFC appTag:'};
                dlgtitle = 'Create new ITFC:';
                definput = {'New_ITFC'};
                dims = [1 50];                
                input = inputdlg(prompt, dlgtitle, dims,  definput);   

                if isempty(input) || isempty(input{1})
                    return
                elseif ~isempty(wfcfw.itfc) && sum(strcmp(input{1}, fieldnames(wfcfw.itfc)))>0 % there is already a submodel with this name
                    answer = questdlg(['The project already contains an ITFC named "' input{1} ...
                        '". Do you want to overwrite it?'], 'Submodel with same name detected', ...
                        'Yes', 'No','No');
                    switch answer
                        case 'Yes'
                            
                        case 'No'
                            return
                    end
                end
                
                % default settings are used                
                tab.appTag = input{1};
                tab.Flag_Generate_PLC_app = false;
                tab.Flag_Create_test_ITFC = false;
                tab.PLCApp_folder = input{1};
                tab.refC_name = input{1};                


                ifc = itfc_app(tab(1,:));
                wfcfw.itfc.(ifc.appTag) = ifc;

                app.Data.wfcfw = wfcfw;                                     
                updateITFCTable(app, wfcfw)

        end

        % Callback function
        function CreatePLCmodbusmappingButtonPushed(app, event)

            error('Modbus mapping is not yet implemented')
            return
            
            wfcfw = app.Data.wfcfw; 
            wfcfw.settings.modbusdict.create_map();

        end

        % Button pushed function: GenerateMATfilefromHOSToutputsButton
        function GenerateMATfilefromHOSToutputsButtonPushed(app, event)

            wfcfw = app.Data.wfcfw; 
            wfcfw.read_test_outputs();

        end

        % Button pushed function: AddHOSTButton
        function AddHOSTButtonPushed(app, event)

            wfcfw = app.Data.wfcfw;
            hostvn = app.Data.host_labels;
            newRow = cell(1, length(hostvn));
            tab = array2table(newRow, 'VariableNames',hostvn);

            prompt = {'HOST appTag:'};
            dlgtitle = 'Create new HOST:';
            definput = {'New_HOST'};
            dims = [1 50];
            input = inputdlg(prompt, dlgtitle, dims,  definput);

            if isempty(input) || isempty(input{1})
                return
            elseif ~isempty(wfcfw.host) && sum(strcmp(input{1}, fieldnames(wfcfw.host)))>0 % there is already a submodel with this name
                answer = questdlg(['The project already contains an HOST named "' input{1} ...
                    '". Do you want to overwrite it?'], 'Submodel with same name detected', ...
                    'Yes', 'No','No');
                switch answer
                    case 'Yes'

                    case 'No'
                        return
                end
            end

            % default settings are used
            tab.appTag = input{1};
            tab.Flag_Generate_PLC_app = false;
            tab.PLCApp_folder = input{1};
            tab.refC_name = input{1};
            tab.FAST_FREQ_RATIO = 1;
            tab.SLOW_FREQ_RATIO = 10;
            tab.CTRL_FREQ_RATIO = 10;
            tab.output_file_duration_s = 600;


            ha = host_app(tab(1,:));
            wfcfw.host.(ha.appTag) = ha;

            app.Data.wfcfw = wfcfw;
            updateHOSTTable(app, wfcfw)


        end

        % Button pushed function: LoadHostDataButton
        function LoadHostDataButtonPushed(app, event)


            [file,path] = uigetfile('*.mat');
            
            fn = fullfile(path, file);
            
            if exist(fn) == 0                
                warning('Input File not found.');
                return
            end
            
            wfcfw = app.Data.wfcfw;


            ha = app.SelectHostDropDown.Value;

            outlist = wfcfw.test.load_host_data(fn, ha, wfcfw);
            
            app.EntriesDropDown.Items = outlist;

        end

        % Drop down opening function: SelectHostDropDown
        function SelectHostDropDownOpening(app, event)
            wfcfw = app.Data.wfcfw;
            host_list = fieldnames(wfcfw.host);

            if isempty(app.SelectHostDropDown.Items) || length(app.SelectHostDropDown.Items) == 1

                app.SelectHostDropDown.Items = host_list;
                SelectHostDropDownValueChanged(app)

            else 

                app.SelectHostDropDown.Items = host_list;

            end


        end

        % Value changed function: EntriesDropDown
        function EntriesDropDownValueChanged(app, event)

           plot_var(app)
           
        end

        % Value changed function: DelaysamplesEditField
        function DelaysamplesEditFieldValueChanged(app, event)

            plot_var(app)

        end

        % Button pushed function: FinddelayButton
        function FinddelayButtonPushed(app, event)

                value = app.EntriesDropDown.Value;
            
                wfcfw = app.Data.wfcfw;

                ha = app.SelectHostDropDown.Value;
           
                ielem = cell2mat(cellfun(@(x) strcmp(x, value), {wfcfw.host.(ha).Variables.TagName}, 'Uniformoutput', false));
                var =  wfcfw.host.(ha).Variables(ielem);

               delay_samples =var.find_delay_test(wfcfw.settings.sample_time);

               app.DelaysamplesEditField.Value = delay_samples;

               plot_var(app)

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            app.DelaysamplesEditField.Value = app.DelaysamplesEditField.Value + 1;
            plot_var(app)

        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
             
            app.DelaysamplesEditField.Value = app.DelaysamplesEditField.Value - 1;
            plot_var(app)   

        end

        % Menu selected function: GridonMenu
        function GridonMenuSelected(app, event)
            grid(app.UIAxes, 'on')
            
        end

        % Menu selected function: BrushdatasyncITFCappMenu
        function BrushdatasyncITFCappMenuSelected(app, event)
            
            h = findobj(app.UIAxes,'type','line');

            
%             clear lineBrushed
   
            id_line = find(cell2mat(cellfun(@(x) strcmpi(x, app.SelectHostDropDown.Value), {h(:).DisplayName}, 'UniformOutput', false )));
            if isempty(id_line)
                error('Could not find specified host line!')
            end

            idx_brushed = logical(get(h(id_line),'BrushData'));

            if isempty(idx_brushed)
                return
            else
                x_ts_brushed = h(id_line).XData(idx_brushed);
                
                x_ts_limits = [x_ts_brushed(1) x_ts_brushed(end)];
                
                % apply those limits to all variables that belong to the
                % same ITFC App

                wfcfw = app.Data.wfcfw;
                ha = app.SelectHostDropDown.Value;
                delay_samples = app.DelaysamplesEditField.Value;
                delay = seconds(wfcfw.settings.sample_time * delay_samples);

                ielem = cell2mat(cellfun(@(x) strcmp(x, app.EntriesDropDown.Value), {wfcfw.host.(ha).Variables.TagName}, 'Uniformoutput', false));
                var =  wfcfw.host.(ha).Variables(ielem);

                app.Data.wfcfw.host.(ha).shift_and_crop_itfcvar(delay, x_ts_limits, var.parent_App); % this function adjust all host variables that belong to the same ITFC app

                app.DelaysamplesEditField.Value = 0; % after i recalibrate the variables i need to set the delay back to zero

                plot_var(app)
            end

        end

        % Button pushed function: SaveButton_2
        function SaveButton_2Pushed(app, event)
            
            prompt = {'Output name:'};
            dlgtitle = 'Save with name:';
            definput = {'project_name'};
            dims = [1 50];
            input = inputdlg(prompt, dlgtitle, dims,  definput);

            if isempty(input) || isempty(input{1})
                return
            end

            wfcfw = app.Data.wfcfw;
            save([input{1} '.mat'], 'wfcfw')


        end

        % Value changed function: SelectHostDropDown
        function SelectHostDropDownValueChanged(app, event)

            value = app.SelectHostDropDown.Value;

            wfcfw = app.Data.wfcfw;
            
            outlist = app.Data.wfcfw.test.get_outputs_list(wfcfw.host.(value) , wfcfw);

            if isempty(outlist)

            else
                app.EntriesDropDown.Items = outlist;
            end


        end

        % Button pushed function: RunSimulinkmodelsButton
        function RunSimulinkmodelsButtonPushed(app, event)
            
            wfcfw = app.Data.wfcfw;

%             ha = app.SelectHostDropDown.Value;

            sm_list = fieldnames(wfcfw.sub_models);

            for ielem = 1 : length(sm_list)

                sm = wfcfw.sub_models.(sm_list{ielem});

                sm.run_simulink_test(wfcfw);

            end


        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            wfcfw = app.Data.wfcfw;            
            updateSubmodelTable(app, wfcfw)
            updateITFCTable(app, wfcfw)
            updateHOSTTable(app, wfcfw)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'TUM-PAL';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DevelopDeployTab = uitab(app.TabGroup);
            app.DevelopDeployTab.Title = 'Develop/Deploy';
            app.LoadButton = uibutton(app.DevelopDeployTab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.AddSUBMODELButton = uibutton(app.DevelopDeployTab, 'push');
            app.AddSUBMODELButton.ButtonPushedFcn = createCallbackFcn(app, @AddSUBMODELButtonPushed, true);
            app.AddSUBMODELButton.Text = 'Add SUBMODEL';
            app.SubmodelsPanel = uipanel(app.DevelopDeployTab);
            app.SubmodelsPanel.Title = 'Submodels';
            app.FilenameEditField_2Label = uilabel(app.DevelopDeployTab);
            app.FilenameEditField_2Label.Text = 'Filename:';
            app.FilenameEditField = uieditfield(app.DevelopDeployTab, 'text');
            app.NewSwitchLabel = uilabel(app.DevelopDeployTab);
            app.NewSwitchLabel.Text = 'New';
            app.NewSwitch = uiswitch(app.DevelopDeployTab, 'slider');
            app.NewSwitch.ValueChangedFcn = createCallbackFcn(app, @NewSwitchValueChanged, true);
            app.InterfacePanel = uipanel(app.DevelopDeployTab);
            app.InterfacePanel.Title = 'Interface';
            app.HOSTPanel = uipanel(app.DevelopDeployTab);
            app.HOSTPanel.Title = 'HOST';
            app.AddITFCButton = uibutton(app.DevelopDeployTab, 'push');
            app.AddITFCButton.ButtonPushedFcn = createCallbackFcn(app, @AddITFCButtonPushed, true);
            app.AddITFCButton.Text = 'Add ITFC';
            app.AddHOSTButton = uibutton(app.DevelopDeployTab, 'push');
            app.AddHOSTButton.ButtonPushedFcn = createCallbackFcn(app, @AddHOSTButtonPushed, true);
            app.AddHOSTButton.Text = 'Add HOST';
            app.GeneratePLCButton = uibutton(app.DevelopDeployTab, 'push');
            app.GeneratePLCButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePLCButtonPushed, true);
            app.GeneratePLCButton.Text = 'Generate PLC';
            app.SaveButton = uibutton(app.DevelopDeployTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.RefreshButton = uibutton(app.DevelopDeployTab, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.TestTab = uitab(app.TabGroup);
            app.TestTab.Title = 'Test';
            app.CheckdatatransmissionPanel = uipanel(app.TestTab);
            app.CheckdatatransmissionPanel.Title = 'Check data transmission';
            app.UIAxes = uiaxes(app.CheckdatatransmissionPanel);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.GenerateMATfilefromHOSToutputsButton = uibutton(app.CheckdatatransmissionPanel, 'push');
            app.GenerateMATfilefromHOSToutputsButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateMATfilefromHOSToutputsButtonPushed, true);
            app.GenerateMATfilefromHOSToutputsButton.Text = 'Generate MAT file from HOST outputs';
            app.LoadHostDataButton = uibutton(app.CheckdatatransmissionPanel, 'push');
            app.LoadHostDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadHostDataButtonPushed, true);
            app.LoadHostDataButton.Text = 'Load Host Data';
            app.EntriesDropDownLabel = uilabel(app.CheckdatatransmissionPanel);
            app.EntriesDropDownLabel.Text = 'Entries';
            app.EntriesDropDown = uidropdown(app.CheckdatatransmissionPanel);
            app.EntriesDropDown.Items = {};
            app.EntriesDropDown.ValueChangedFcn = createCallbackFcn(app, @EntriesDropDownValueChanged, true);
            app.EntriesDropDown.Value = {};
            app.SelectHostDropDownLabel = uilabel(app.CheckdatatransmissionPanel);
            app.SelectHostDropDownLabel.Text = 'Select Host';
            app.SelectHostDropDown = uidropdown(app.CheckdatatransmissionPanel);
            app.SelectHostDropDown.Items = {};
            app.SelectHostDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SelectHostDropDownOpening, true);
            app.SelectHostDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectHostDropDownValueChanged, true);
            app.SelectHostDropDown.Value = {};
            app.RunSimulinkmodelsButton = uibutton(app.CheckdatatransmissionPanel, 'push');
            app.RunSimulinkmodelsButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulinkmodelsButtonPushed, true);
            app.RunSimulinkmodelsButton.Text = 'Run Simulink models';
            app.FinddelayButton = uibutton(app.TestTab, 'push');
            app.FinddelayButton.ButtonPushedFcn = createCallbackFcn(app, @FinddelayButtonPushed, true);
            app.FinddelayButton.Text = 'Find delay';
            app.DelaysamplesEditFieldLabel = uilabel(app.TestTab);
            app.DelaysamplesEditFieldLabel.Text = 'Delay [samples]';
            app.DelaysamplesEditField = uieditfield(app.TestTab, 'numeric');
            app.DelaysamplesEditField.ValueChangedFcn = createCallbackFcn(app, @DelaysamplesEditFieldValueChanged, true);
            app.Button = uibutton(app.TestTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '+';
            app.Button_2 = uibutton(app.TestTab, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '-';
            app.SaveButton_2 = uibutton(app.TestTab, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_2Pushed, true);
            app.SaveButton_2.Text = 'Save';
            app.ContextMenu_Cref = uicontextmenu(app.UIFigure);
            app.AddMatlabFieldsMenu = uimenu(app.ContextMenu_Cref);
            app.AddMatlabFieldsMenu.Text = 'Add Matlab Fields';
            app.ContextMenuPlot = uicontextmenu(app.UIFigure);
            app.GridonMenu = uimenu(app.ContextMenuPlot);
            app.GridonMenu.MenuSelectedFcn = createCallbackFcn(app, @GridonMenuSelected, true);
            app.GridonMenu.Text = 'Grid on';
            app.BrushdatasyncITFCappMenu = uimenu(app.ContextMenuPlot);
            app.BrushdatasyncITFCappMenu.MenuSelectedFcn = createCallbackFcn(app, @BrushdatasyncITFCappMenuSelected, true);
            app.BrushdatasyncITFCappMenu.Text = 'Brush data / sync ITFC app';
            % Assign app.ContextMenuPlot
            app.UIAxes.ContextMenu = app.ContextMenuPlot;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end